classdef sEMG < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        ReadfileButton                matlab.ui.control.Button
        SettingsPanel                 matlab.ui.container.Panel
        PlottheButtonGroup            matlab.ui.container.ButtonGroup
        normalizeddataButton          matlab.ui.control.RadioButton
        movingrmsofthedataButton      matlab.ui.control.RadioButton
        dataSavitzkyGolayfilteredButton  matlab.ui.control.RadioButton
        databandpassfilteredButton    matlab.ui.control.RadioButton
        datawithoutoutliersButton     matlab.ui.control.RadioButton
        fouriertransformButton        matlab.ui.control.RadioButton
        datainmVButton                matlab.ui.control.RadioButton
        DataofButtonGroup             matlab.ui.container.ButtonGroup
        allmusclesButton              matlab.ui.control.RadioButton
        selectedmuscleButton          matlab.ui.control.RadioButton
        ChoosemuscleButtonGroup       matlab.ui.container.ButtonGroup
        trapezius_rightButton         matlab.ui.control.RadioButton
        trapezius_leftButton          matlab.ui.control.RadioButton
        delt_rightButton              matlab.ui.control.RadioButton
        delt_leftButton               matlab.ui.control.RadioButton
        ECR_rightButton               matlab.ui.control.RadioButton
        RightPanel                    matlab.ui.container.Panel
        OutlierremovalsettingsPanel   matlab.ui.container.Panel
        WindowsizeEditField           matlab.ui.control.EditField
        WindowsizeEditFieldLabel      matlab.ui.control.Label
        DeviationnumberEditField      matlab.ui.control.EditField
        DeviationnumberLabel          matlab.ui.control.Label
        MovingRMSsettingsPanel        matlab.ui.container.Panel
        windowlengthEditField         matlab.ui.control.EditField
        windowlengthEditFieldLabel    matlab.ui.control.Label
        ExportButton                  matlab.ui.control.Button
        SavitzkyGolayfiltersettingsPanel  matlab.ui.container.Panel
        framelengthEditField          matlab.ui.control.EditField
        framelengthEditFieldLabel     matlab.ui.control.Label
        orderEditField                matlab.ui.control.EditField
        orderEditFieldLabel           matlab.ui.control.Label
        DonePanel                     matlab.ui.container.Panel
        DoneButton                    matlab.ui.control.Button
        BandpassfiltersettingsPanel   matlab.ui.container.Panel
        highcutofffrequencyEditField  matlab.ui.control.EditField
        highcutofffrequencyEditFieldLabel  matlab.ui.control.Label
        lowcutofffrequencyEditField   matlab.ui.control.EditField
        lowcutofffrequencyEditFieldLabel  matlab.ui.control.Label
        PlotPanel_7                   matlab.ui.container.Panel
        UIAxes7                       matlab.ui.control.UIAxes
        PlotPanel_6                   matlab.ui.container.Panel
        UIAxes6                       matlab.ui.control.UIAxes
        PlotPanel_5                   matlab.ui.container.Panel
        UIAxes5                       matlab.ui.control.UIAxes
        PlotPanel_4                   matlab.ui.container.Panel
        UIAxes4                       matlab.ui.control.UIAxes
        PlotPanel_3                   matlab.ui.container.Panel
        UIAxes3                       matlab.ui.control.UIAxes
        PlotPanel_2                   matlab.ui.container.Panel
        UIAxes2                       matlab.ui.control.UIAxes
        PlotPanel                     matlab.ui.container.Panel
        UIAxes                        matlab.ui.control.UIAxes
        sEMGLabel                     matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        path = ''
        file = ''
        header = []
        data = [0,0,0,0,0]
        M_mV = [0,0,0,0,0]
        samplingRate
        fourier = [0,0,0,0,0]
        no_outliers = [0,0,0,0,0]
        filtered = [0,0,0,0,0]
        sgf = [0,0,0,0,0]
        rms = [0,0,0,0,0]
        movrmsExp = 0
        windowLength = 20
        NormalizedData = [0,0,0,0,0]
        overlap = 0
        deviationNumber = 2
        windowSize = 20
        lowCutoffFreq = 100
        highCutoffFreq = 300
        order = 20
        frameLength = 219
    end
    methods (Access = public)               
        function plotFunction(app, column)
            legend(app.UIAxes, 'off');
            legend(app.UIAxes2, 'off');
            legend(app.UIAxes3, 'off');
            legend(app.UIAxes4, 'off');
            legend(app.UIAxes5, 'off');
            legend(app.UIAxes6, 'off');
            legend(app.UIAxes7, 'off');
            
            plot(app.UIAxes, (1:length(app.M_mV(:,column)))', app.M_mV(:,column))
            xlim(app.UIAxes, [0, length(app.M_mV(:,column))]);
            
            plot(app.UIAxes2, (1:length(app.fourier(:,column)))', app.fourier(:,column))
            xlim(app.UIAxes2, [0, length(app.fourier(:,column))]);
            
            plot(app.UIAxes3, (1:length(app.no_outliers(:,column)))', app.no_outliers(:,column))
            xlim(app.UIAxes3, [0, length(app.no_outliers(:,column))]);
            
            plot(app.UIAxes4, (1:length(app.filtered(:,column)))', app.filtered(:,column))
            xlim(app.UIAxes4, [0, length(app.filtered(:,column))]);
            
            plot(app.UIAxes5, (1:length(app.sgf(:,column)))', app.sgf(:,column))
            xlim(app.UIAxes5, [0, length(app.sgf(:,column))]);
            
            if (app.movrmsExp ~= 0)
                y = abs(app.data(:,column));
                app.rms = app.movrmsExp(y)';
                plot(app.UIAxes6, (1:length(app.rms(app.windowLength:end)))', app.rms(app.windowLength:end))
                xlim(app.UIAxes6, [0, length(app.rms(app.windowLength:end))]);
                
                plot(app.UIAxes7, (1:length(app.NormalizedData(:,column)))', app.NormalizedData(:,column))
                xlim(app.UIAxes7, [0, length(app.NormalizedData(:,column))]);
            end
        end
        
        function plotAll(app)
            cla(app.UIAxes);
            cla(app.UIAxes2);
            cla(app.UIAxes3);
            cla(app.UIAxes4);
            cla(app.UIAxes5);
            cla(app.UIAxes6);
            cla(app.UIAxes7);

            hold(app.UIAxes, 'on');
            plot(app.UIAxes, (1:length(app.M_mV(:,1)))', app.M_mV(:,1))
            xlim(app.UIAxes, [0, length(app.M_mV(:,1))]);
            plot(app.UIAxes, (1:length(app.M_mV(:,2)))', app.M_mV(:,2))
            xlim(app.UIAxes, [0, length(app.M_mV(:,2))]);
            plot(app.UIAxes, (1:length(app.M_mV(:,3)))', app.M_mV(:,3))
            xlim(app.UIAxes, [0, length(app.M_mV(:,3))]);
            plot(app.UIAxes, (1:length(app.M_mV(:,4)))', app.M_mV(:,4))
            xlim(app.UIAxes, [0, length(app.M_mV(:,4))]);
            plot(app.UIAxes, (1:length(app.M_mV(:,5)))', app.M_mV(:,5))
            legend(app.UIAxes, {'channel1', 'channel2', 'channel3', 'channel4', 'channel5'}, 'Location', 'Best');
            xlim(app.UIAxes, [0, length(app.M_mV(:,5))]);
            hold(app.UIAxes, 'off');
            
            hold(app.UIAxes2, 'on');
            plot(app.UIAxes2, (1:length(app.fourier(:,1)))', app.fourier(:,1))
            xlim(app.UIAxes2, [0, length(app.fourier(:,1))]);
            plot(app.UIAxes2, (1:length(app.fourier(:,2)))', app.fourier(:,2))
            xlim(app.UIAxes2, [0, length(app.fourier(:,2))]);
            plot(app.UIAxes2, (1:length(app.fourier(:,3)))', app.fourier(:,3))
            xlim(app.UIAxes2, [0, length(app.fourier(:,3))]);
            plot(app.UIAxes2, (1:length(app.fourier(:,4)))', app.fourier(:,4))
            xlim(app.UIAxes2, [0, length(app.fourier(:,4))]);
            plot(app.UIAxes2, (1:length(app.fourier(:,5)))', app.fourier(:,5))
            legend(app.UIAxes2, {'channel1', 'channel2', 'channel3', 'channel4', 'channel5'}, 'Location', 'Best');
            xlim(app.UIAxes2, [0, length(app.fourier(:,5))]);
            hold(app.UIAxes2, 'off');
            
            hold(app.UIAxes3, 'on');
            plot(app.UIAxes3, (1:length(app.no_outliers(:,1)))', app.no_outliers(:,1))
            xlim(app.UIAxes3, [0, length(app.no_outliers(:,1))]);
            plot(app.UIAxes3, (1:length(app.no_outliers(:,2)))', app.no_outliers(:,2))
            xlim(app.UIAxes3, [0, length(app.no_outliers(:,2))]);
            plot(app.UIAxes3, (1:length(app.no_outliers(:,3)))', app.no_outliers(:,3))
            xlim(app.UIAxes3, [0, length(app.no_outliers(:,3))]);
            plot(app.UIAxes3, (1:length(app.no_outliers(:,4)))', app.no_outliers(:,4))
            xlim(app.UIAxes3, [0, length(app.no_outliers(:,4))]);
            plot(app.UIAxes3, (1:length(app.no_outliers(:,5)))', app.no_outliers(:,5))
            legend(app.UIAxes3, {'channel1', 'channel2', 'channel3', 'channel4', 'channel5'}, 'Location', 'Best');
            xlim(app.UIAxes3, [0, length(app.no_outliers(:,5))]);
            hold(app.UIAxes3, 'off');
            
            hold(app.UIAxes4, 'on');
            plot(app.UIAxes4, (1:length(app.filtered(:,1)))', app.filtered(:,1))
            xlim(app.UIAxes4, [0, length(app.filtered(:,1))]);
            plot(app.UIAxes4, (1:length(app.filtered(:,2)))', app.filtered(:,2))
            xlim(app.UIAxes4, [0, length(app.filtered(:,2))]);
            plot(app.UIAxes4, (1:length(app.filtered(:,3)))', app.filtered(:,3))
            xlim(app.UIAxes4, [0, length(app.filtered(:,3))]);
            plot(app.UIAxes4, (1:length(app.filtered(:,4)))', app.filtered(:,4))
            xlim(app.UIAxes4, [0, length(app.filtered(:,4))]);
            plot(app.UIAxes4, (1:length(app.filtered(:,5)))', app.filtered(:,5))
            legend(app.UIAxes4, {'channel1', 'channel2', 'channel3', 'channel4', 'channel5'}, 'Location', 'Best');
            xlim(app.UIAxes4, [0, length(app.filtered(:,5))]);
            hold(app.UIAxes4, 'off');
            
            hold(app.UIAxes5, 'on');
            plot(app.UIAxes5, (1:length(app.sgf(:,1)))', app.sgf(:,1))
            xlim(app.UIAxes5, [0, length(app.sgf(:,1))]);
            plot(app.UIAxes5, (1:length(app.sgf(:,2)))', app.sgf(:,2))
            xlim(app.UIAxes5, [0, length(app.sgf(:,2))]);
            plot(app.UIAxes5, (1:length(app.sgf(:,3)))', app.sgf(:,3))
            xlim(app.UIAxes5, [0, length(app.sgf(:,3))]);
            plot(app.UIAxes5, (1:length(app.sgf(:,4)))', app.sgf(:,4))
            xlim(app.UIAxes5, [0, length(app.sgf(:,4))]);
            plot(app.UIAxes5, (1:length(app.sgf(:,5)))', app.sgf(:,5))
            legend(app.UIAxes5, {'channel1', 'channel2', 'channel3', 'channel4', 'channel5'}, 'Location', 'Best');
            xlim(app.UIAxes5, [0, length(app.sgf(:,5))]);
            hold(app.UIAxes5, 'off');
            
            if (app.movrmsExp ~= 0)
                hold(app.UIAxes6, 'on');
                y = abs(app.data(:,1));
                app.rms = app.movrmsExp(y)';
                plot(app.UIAxes6, (1:length(app.rms(app.windowLength:end)))', app.rms(app.windowLength:end))
                xlim(app.UIAxes6, [0, length(app.rms(app.windowLength:end))]);
                y = abs(app.data(:,2));
                app.rms = app.movrmsExp(y)';
                plot(app.UIAxes6, (1:length(app.rms(app.windowLength:end)))', app.rms(app.windowLength:end))
                xlim(app.UIAxes6, [0, length(app.rms(app.windowLength:end))]);
                y = abs(app.data(:,3));
                app.rms = app.movrmsExp(y)';
                plot(app.UIAxes6, (1:length(app.rms(app.windowLength:end)))', app.rms(app.windowLength:end))
                xlim(app.UIAxes6, [0, length(app.rms(app.windowLength:end))]);
                y = abs(app.data(:,4));
                app.rms = app.movrmsExp(y)';
                plot(app.UIAxes6, (1:length(app.rms(app.windowLength:end)))', app.rms(app.windowLength:end))
                xlim(app.UIAxes6, [0, length(app.rms(app.windowLength:end))]);
                y = abs(app.data(:,5));
                app.rms = app.movrmsExp(y)';
                plot(app.UIAxes6, (1:length(app.rms(app.windowLength:end)))', app.rms(app.windowLength:end))
                legend(app.UIAxes6, {'channel1', 'channel2', 'channel3', 'channel4', 'channel5'}, 'Location', 'Best');
                xlim(app.UIAxes6, [0, length(app.rms(app.windowLength:end))]);
                hold(app.UIAxes6, 'off');
            end
            
            hold(app.UIAxes7, 'on');
            plot(app.UIAxes7, (1:length(app.NormalizedData(:,1)))', app.NormalizedData(:,1))
            xlim(app.UIAxes7, [0, length(app.NormalizedData(:,1))]);
            plot(app.UIAxes7, (1:length(app.NormalizedData(:,2)))', app.NormalizedData(:,2))
            xlim(app.UIAxes7, [0, length(app.NormalizedData(:,2))]);
            plot(app.UIAxes7, (1:length(app.NormalizedData(:,3)))', app.NormalizedData(:,3))
            xlim(app.UIAxes7, [0, length(app.NormalizedData(:,3))]);
            plot(app.UIAxes7, (1:length(app.NormalizedData(:,4)))', app.NormalizedData(:,4))
            xlim(app.UIAxes7, [0, length(app.NormalizedData(:,4))]);
            plot(app.UIAxes7, (1:length(app.NormalizedData(:,5)))', app.NormalizedData(:,5))
            legend(app.UIAxes7, {'channel1', 'channel2', 'channel3', 'channel4', 'channel5'}, 'Location', 'Best');
            xlim(app.UIAxes7, [0, length(app.NormalizedData(:,5))]);     
            hold(app.UIAxes7, 'off');
        end
        
        function update(app)
            %% Transform raw data to mV
            app.M_mV = transformTo_mV(app.data, app.header.resolution);
            
            %% FFT analysis   
            fourierall = fft(app.data,[],1);
            app.fourier = abs(fourierall(1:(length(fourierall)/2),:));
            % filter peak value
            app.fourier(1,:)=0;
            
            %% Outlier removal
            app.no_outliers = FilterOutlierWindow(app.M_mV,app.deviationNumber,app.windowSize);
            
            %%% Processing track A
            % rectify data
            app.no_outliers = abs(app.no_outliers);
            
            %% Bandpassfilter
            % cutoffrequency
            app.filtered = BandpassFilter(app.header,app.no_outliers,app.lowCutoffFreq,app.highCutoffFreq,app.samplingRate);

            %% Savitzky-Golay filtering
            app.sgf = Savitzky_GolayFilter(app.no_outliers,app.order,app.frameLength);
            
            %%% Processing track B
            %% Moving RMS
            app.overlap = app.windowLength -1; % default windowLength -1
            app.movrmsExp = dsp.MovingRMS('WindowLength', app.windowLength, 'OverlapLength', app.overlap);

            %% MVC Normalisation
            app.NormalizedData = MVC(app.no_outliers,app.path,app.file);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            event = events('pass');
            
            % set default visibility of plots
            app.PlotPanel.Visible = 'on';
            app.PlotPanel_2.Visible = 'off';
            app.PlotPanel_3.Visible = 'off';
            app.PlotPanel_4.Visible = 'off';
            app.PlotPanel_5.Visible = 'off';
            app.PlotPanel_6.Visible = 'off';
            app.PlotPanel_7.Visible = 'off';
            app.DonePanel.Visible = 'off';
            app.OutlierremovalsettingsPanel.Visible = 'off';
            app.BandpassfiltersettingsPanel.Visible = 'off';
            app.SavitzkyGolayfiltersettingsPanel.Visible = 'off';
            app.MovingRMSsettingsPanel.Visible = 'off';
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {210, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Selection changed function: ChoosemuscleButtonGroup
        function ChoosemuscleButtonGroupSelectionChanged(app, event)
            selectedButton = app.ChoosemuscleButtonGroup.SelectedObject;
            switch selectedButton
                case app.ECR_rightButton
                    plotFunction(app, 5);
                case app.delt_leftButton
                    plotFunction(app, 3);                  
                case app.delt_rightButton
                    plotFunction(app, 4);
                case app.trapezius_leftButton
                    plotFunction(app, 1);
                case app.trapezius_rightButton
                    plotFunction(app, 2);
                otherwise
            end
        end

        % Selection changed function: DataofButtonGroup
        function DataofButtonGroupSelectionChanged(app, event)
            selectedButton = app.DataofButtonGroup.SelectedObject;
            if (selectedButton == app.selectedmuscleButton)
                app.ChoosemuscleButtonGroup.Visible = 'on';
                ChoosemuscleButtonGroupSelectionChanged(app, event)
            elseif (selectedButton == app.allmusclesButton)
                app.ChoosemuscleButtonGroup.Visible = 'off';
                plotAll(app);
            end
        end

        % Button pushed function: ReadfileButton
        function ReadfileButtonPushed(app, event)
            %% Load raw data
            [app.file,app.path] = uigetfile('*.txt');
            figure(app.UIFigure);
            fullPath = append(app.path, app.file);
            [app.header, app.data] = ReadFile(fullPath);
            app.samplingRate = app.header.samplingRate;
            
            update(app);
            plotFunction(app, 1);
        end

        % Selection changed function: PlottheButtonGroup
        function PlottheButtonGroupSelectionChanged(app, event)
            selectedButton = app.PlottheButtonGroup.SelectedObject;
            app.PlotPanel.Visible = 'off';
            app.PlotPanel_2.Visible = 'off';
            app.PlotPanel_3.Visible = 'off';
            app.PlotPanel_4.Visible = 'off';
            app.PlotPanel_5.Visible = 'off';
            app.PlotPanel_6.Visible = 'off';
            app.PlotPanel_7.Visible = 'off';
            app.DonePanel.Visible = 'off';
            app.OutlierremovalsettingsPanel.Visible = 'off';
            app.BandpassfiltersettingsPanel.Visible = 'off';
            app.SavitzkyGolayfiltersettingsPanel.Visible = 'off';
            app.MovingRMSsettingsPanel.Visible = 'off';
            
            if (selectedButton == app.datainmVButton)
                app.PlotPanel.Visible = 'on';
            elseif (selectedButton == app.fouriertransformButton)
                app.PlotPanel_2.Visible = 'on';
            elseif (selectedButton == app.datawithoutoutliersButton)
                app.PlotPanel_3.Visible = 'on';
                app.OutlierremovalsettingsPanel.Visible = 'on';
                app.DonePanel.Visible = 'on';
            elseif (selectedButton == app.databandpassfilteredButton)
                app.PlotPanel_4.Visible = 'on';
                app.BandpassfiltersettingsPanel.Visible = 'on';
                app.DonePanel.Visible = 'on';
            elseif (selectedButton == app.dataSavitzkyGolayfilteredButton)
                app.PlotPanel_5.Visible = 'on';
                app.SavitzkyGolayfiltersettingsPanel.Visible = 'on';
                app.DonePanel.Visible = 'on';
            elseif (selectedButton == app.movingrmsofthedataButton)
                app.PlotPanel_6.Visible = 'on';
                app.MovingRMSsettingsPanel.Visible = 'on';
                app.DonePanel.Visible = 'on';
            elseif (selectedButton == app.normalizeddataButton)
                app.PlotPanel_7.Visible = 'on';
            end
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            update(app);
            DataofButtonGroupSelectionChanged(app, event);
        end

        % Value changed function: DeviationnumberEditField
        function DeviationnumberEditFieldValueChanged(app, event)
            value = app.DeviationnumberEditField.Value;
            [x,tf] = str2num(value);
            if (tf)
                app.deviationNumber = x;
            else
                
                uialert(app.UIFigure, "Invalid deviation number!","Warning")
            end
        end

        % Value changed function: WindowsizeEditField
        function WindowsizeEditFieldValueChanged(app, event)
            value = app.WindowsizeEditField.Value;
            [x,tf] = str2num(value);
            if (tf)
                app.windowSize = x;
            else
                uialert(app.UIFigure, "Invalid window size!","Warning")
            end
        end

        % Value changed function: lowcutofffrequencyEditField
        function lowcutofffrequencyEditFieldValueChanged(app, event)
            value = app.lowcutofffrequencyEditField.Value;
            [x,tf] = str2num(value);
            if (tf)
                app.lowCutoffFreq= x;
            else
                
                uialert(app.UIFigure, "Invalid low frequency!","Warning")
            end
        end

        % Value changed function: highcutofffrequencyEditField
        function highcutofffrequencyEditFieldValueChanged(app, event)
            value = app.highcutofffrequencyEditField.Value;
            [x,tf] = str2num(value);
            if (tf)
                if (x>app.lowCutoffFreq)
                    app.highCutoffFreq = x;
                else
                    uialert(app.UIFigure, "Low cutoff frequency is higher than the high cutoff frequency!","Warning")
                end
            else
                uialert(app.UIFigure, "Invalid high frequency!","Warning")
            end
        end

        % Value changed function: orderEditField
        function orderEditFieldValueChanged(app, event)
            value = app.orderEditField.Value;
            app.order = str2double(value);
        end

        % Value changed function: framelengthEditField
        function framelengthEditFieldValueChanged(app, event)
            value = app.framelengthEditField.Value;
            app.frameLength = str2double(value);
        end

        % Value changed function: windowlengthEditField
        function windowlengthEditFieldValueChanged(app, event)
            value = app.windowlengthEditField.Value;
            app.windowLength = str2double(value);
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            %% Data export
            if (app.data ~= [0,0,0,0,0])
                fileId = fopen('settings.txt','w');
                fprintf(fileId,"Date: %s  & Time: %s \n",app.header.date, app.header.time);
                fprintf(fileId,"Sampling frequency: %d  & Resolution: %d \n", app.header.samplingRate, app.header.resolution(1));
                fprintf(fileId,"Settings used: \n StandardDeviation to remove outliers: %d, windowSize for replacing outliers: %d\n",app.deviationNumber, app.windowSize);
                fprintf(fileId,"Process track A:\n");
                fprintf(fileId,"    Bandpassfilter: cuttoffrequencies: %d %d\n", app.lowCutoffFreq, app.highCutoffFreq);
                fprintf(fileId,"    Savitzky-Golay filtering: ordel and framelen: %d %d\n", app.order, app.frameLength);
                fprintf(fileId,"Process track B:\n");
                fprintf(fileId,"    Windowlength, %d", app.windowLength);
                fprintf(fileId,"    Overlap, %d",app.overlap);
                
                writematrix(app.no_outliers,'Processed Data before normalization.csv','Delimiter',';')
                writematrix(app.NormalizedData,'Processed Data after normalization.csv','Delimiter',';')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SettingsPanel = uipanel(app.LeftPanel);
            app.SettingsPanel.Title = 'Settings';
            app.ChoosemuscleButtonGroup = uibuttongroup(app.SettingsPanel);
            app.ChoosemuscleButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ChoosemuscleButtonGroupSelectionChanged, true);
            app.ChoosemuscleButtonGroup.Title = 'Choose muscle';
            app.ECR_rightButton = uiradiobutton(app.ChoosemuscleButtonGroup);
            app.ECR_rightButton.Text = 'ECR_right';
            app.delt_leftButton = uiradiobutton(app.ChoosemuscleButtonGroup);
            app.delt_leftButton.Text = 'delt_left';
            app.delt_rightButton = uiradiobutton(app.ChoosemuscleButtonGroup);
            app.delt_rightButton.Text = 'delt_right';
            app.trapezius_leftButton = uiradiobutton(app.ChoosemuscleButtonGroup);
            app.trapezius_leftButton.Text = 'trapezius_left';
            app.trapezius_leftButton.Value = true;
            app.trapezius_rightButton = uiradiobutton(app.ChoosemuscleButtonGroup);
            app.trapezius_rightButton.Text = 'trapezius_right';
            app.DataofButtonGroup = uibuttongroup(app.SettingsPanel);
            app.DataofButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DataofButtonGroupSelectionChanged, true);
            app.DataofButtonGroup.Title = 'Data of';
            app.selectedmuscleButton = uiradiobutton(app.DataofButtonGroup);
            app.selectedmuscleButton.Text = 'selected muscle';
            app.selectedmuscleButton.Value = true;
            app.allmusclesButton = uiradiobutton(app.DataofButtonGroup);
            app.allmusclesButton.Text = 'all muscles';
            app.PlottheButtonGroup = uibuttongroup(app.SettingsPanel);
            app.PlottheButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlottheButtonGroupSelectionChanged, true);
            app.PlottheButtonGroup.Title = 'Plot the';
            app.datainmVButton = uiradiobutton(app.PlottheButtonGroup);
            app.datainmVButton.Text = 'data in mV';
            app.datainmVButton.Value = true;
            app.fouriertransformButton = uiradiobutton(app.PlottheButtonGroup);
            app.fouriertransformButton.Text = 'fourier transform';
            app.datawithoutoutliersButton = uiradiobutton(app.PlottheButtonGroup);
            app.datawithoutoutliersButton.Text = 'data without outliers';
            app.databandpassfilteredButton = uiradiobutton(app.PlottheButtonGroup);
            app.databandpassfilteredButton.Text = 'data bandpass filtered';
            app.dataSavitzkyGolayfilteredButton = uiradiobutton(app.PlottheButtonGroup);
            app.dataSavitzkyGolayfilteredButton.Text = 'data Savitzky-Golay filtered';
            app.movingrmsofthedataButton = uiradiobutton(app.PlottheButtonGroup);
            app.movingrmsofthedataButton.Text = 'moving rms of the data';
            app.normalizeddataButton = uiradiobutton(app.PlottheButtonGroup);
            app.normalizeddataButton.Text = 'normalized data';
            app.ReadfileButton = uibutton(app.LeftPanel, 'push');
            app.ReadfileButton.ButtonPushedFcn = createCallbackFcn(app, @ReadfileButtonPushed, true);
            app.ReadfileButton.Text = 'Read file';
            app.RightPanel = uipanel(app.GridLayout);
            app.sEMGLabel = uilabel(app.RightPanel);
            app.sEMGLabel.Text = 'sEMG';
            app.PlotPanel = uipanel(app.RightPanel);
            app.PlotPanel.Title = 'Plot';
            app.UIAxes = uiaxes(app.PlotPanel);
            title(app.UIAxes, 'Raw data in mV')
            xlabel(app.UIAxes, 'time (s)')
            ylabel(app.UIAxes, 'Voltage (mV)')
            app.PlotPanel_2 = uipanel(app.RightPanel);
            app.PlotPanel_2.Title = 'Plot';
            app.UIAxes2 = uiaxes(app.PlotPanel_2);
            title(app.UIAxes2, 'FFT')
            xlabel(app.UIAxes2, 'frequency (Hz)')
            ylabel(app.UIAxes2, 'Voltage (mV)')
            app.PlotPanel_3 = uipanel(app.RightPanel);
            app.PlotPanel_3.Title = 'Plot';
            app.UIAxes3 = uiaxes(app.PlotPanel_3);
            title(app.UIAxes3, 'Without Outliers')
            xlabel(app.UIAxes3, 'time (s)')
            ylabel(app.UIAxes3, 'Voltage (mV)')
            app.PlotPanel_4 = uipanel(app.RightPanel);
            app.PlotPanel_4.Title = 'Plot';
            app.UIAxes4 = uiaxes(app.PlotPanel_4);
            title(app.UIAxes4, 'with bandpass filter')
            xlabel(app.UIAxes4, 'time (s)')
            ylabel(app.UIAxes4, 'Voltage (mV)')
            app.PlotPanel_5 = uipanel(app.RightPanel);
            app.PlotPanel_5.Title = 'Plot';
            app.UIAxes5 = uiaxes(app.PlotPanel_5);
            title(app.UIAxes5, 'with Savitzky-Golay filter')
            xlabel(app.UIAxes5, 'time (s)')
            ylabel(app.UIAxes5, 'Voltage (mV)')
            app.PlotPanel_6 = uipanel(app.RightPanel);
            app.PlotPanel_6.Title = 'Plot';
            app.UIAxes6 = uiaxes(app.PlotPanel_6);
            title(app.UIAxes6, 'moving rms')
            xlabel(app.UIAxes6, 'time (s)')
            ylabel(app.UIAxes6, 'Voltage (mV)')
            app.PlotPanel_7 = uipanel(app.RightPanel);
            app.PlotPanel_7.Title = 'Plot';
            app.UIAxes7 = uiaxes(app.PlotPanel_7);
            title(app.UIAxes7, 'Normalized')
            xlabel(app.UIAxes7, 'time (s)')
            ylabel(app.UIAxes7, 'Voltage (mV)')
            app.BandpassfiltersettingsPanel = uipanel(app.RightPanel);
            app.BandpassfiltersettingsPanel.Title = 'Bandpass filter settings';
            app.lowcutofffrequencyEditFieldLabel = uilabel(app.BandpassfiltersettingsPanel);
            app.lowcutofffrequencyEditFieldLabel.Text = 'low cutoff frequency';
            app.lowcutofffrequencyEditField = uieditfield(app.BandpassfiltersettingsPanel, 'text');
            app.lowcutofffrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @lowcutofffrequencyEditFieldValueChanged, true);
            app.lowcutofffrequencyEditField.Value = '100';
            app.highcutofffrequencyEditFieldLabel = uilabel(app.BandpassfiltersettingsPanel);
            app.highcutofffrequencyEditFieldLabel.Text = 'high cutoff frequency';
            app.highcutofffrequencyEditField = uieditfield(app.BandpassfiltersettingsPanel, 'text');
            app.highcutofffrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @highcutofffrequencyEditFieldValueChanged, true);
            app.highcutofffrequencyEditField.Value = '300';
            app.DonePanel = uipanel(app.RightPanel);
            app.DoneButton = uibutton(app.DonePanel, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.SavitzkyGolayfiltersettingsPanel = uipanel(app.RightPanel);
            app.SavitzkyGolayfiltersettingsPanel.Title = 'Savitzky-Golay filter settings';
            app.orderEditFieldLabel = uilabel(app.SavitzkyGolayfiltersettingsPanel);
            app.orderEditFieldLabel.Text = 'order';
            app.orderEditField = uieditfield(app.SavitzkyGolayfiltersettingsPanel, 'text');
            app.orderEditField.ValueChangedFcn = createCallbackFcn(app, @orderEditFieldValueChanged, true);
            app.orderEditField.Value = '20';
            app.framelengthEditFieldLabel = uilabel(app.SavitzkyGolayfiltersettingsPanel);
            app.framelengthEditFieldLabel.Text = 'frame length';
            app.framelengthEditField = uieditfield(app.SavitzkyGolayfiltersettingsPanel, 'text');
            app.framelengthEditField.ValueChangedFcn = createCallbackFcn(app, @framelengthEditFieldValueChanged, true);
            app.framelengthEditField.Value = '219';
            app.ExportButton = uibutton(app.RightPanel, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.MovingRMSsettingsPanel = uipanel(app.RightPanel);
            app.MovingRMSsettingsPanel.Title = 'Moving RMS settings';
            app.windowlengthEditFieldLabel = uilabel(app.MovingRMSsettingsPanel);
            app.windowlengthEditFieldLabel.Text = 'window length';
            app.windowlengthEditField = uieditfield(app.MovingRMSsettingsPanel, 'text');
            app.windowlengthEditField.ValueChangedFcn = createCallbackFcn(app, @windowlengthEditFieldValueChanged, true);
            app.windowlengthEditField.Value = '20';
            app.OutlierremovalsettingsPanel = uipanel(app.RightPanel);
            app.OutlierremovalsettingsPanel.Title = 'Outlier removal settings';
            app.DeviationnumberLabel = uilabel(app.OutlierremovalsettingsPanel);
            app.DeviationnumberLabel.Text = 'Deviation number';
            app.DeviationnumberEditField = uieditfield(app.OutlierremovalsettingsPanel, 'text');
            app.DeviationnumberEditField.ValueChangedFcn = createCallbackFcn(app, @DeviationnumberEditFieldValueChanged, true);
            app.DeviationnumberEditField.Value = '2';
            app.WindowsizeEditFieldLabel = uilabel(app.OutlierremovalsettingsPanel);
            app.WindowsizeEditFieldLabel.Text = 'Window size';
            app.WindowsizeEditField = uieditfield(app.OutlierremovalsettingsPanel, 'text');
            app.WindowsizeEditField.ValueChangedFcn = createCallbackFcn(app, @WindowsizeEditFieldValueChanged, true);
            app.WindowsizeEditField.Value = '20';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sEMG

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end