classdef tutorialApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        UIAxes                   matlab.ui.control.UIAxes
        UIAxes2                  matlab.ui.control.UIAxes
        sEMGLabel                matlab.ui.control.Label
        UIAxes3                  matlab.ui.control.UIAxes
        SettingsPanel            matlab.ui.container.Panel
        ChoosemuscleButtonGroup  matlab.ui.container.ButtonGroup
        ECR_rightButton          matlab.ui.control.RadioButton
        delt_leftButton          matlab.ui.control.RadioButton
        delt_rightButton         matlab.ui.control.RadioButton
        trapezius_leftButton     matlab.ui.control.RadioButton
        trapezius_rightButton    matlab.ui.control.RadioButton
        UITable                  matlab.ui.control.Table
        DataofButtonGroup        matlab.ui.container.ButtonGroup
        selectedmuscleButton     matlab.ui.control.RadioButton
        allmusclesButton         matlab.ui.control.RadioButton
        ReadfileButton           matlab.ui.control.Button
    end

    
    properties (Access = private)
        isPressed = false;
        header = []
        data = [0]
        column = 1
        fourier = [0]
    end
    methods (Access = public)
%         function [header, M] = ReadFile(file, path)
%             %[file,path] = uigetfile('*.txt')
%             %read header
%             COMMANDS = regexp(fileread(path + file), '#', 'split');
%             if isempty(COMMANDS{end}); COMMANDS(end) = []; end  %end of file correction
%             DATA = char(COMMANDS(3));
%             A = jsondecode(DATA);
%             Header = A.x00_07_80_3B_46_63;
%             
%             %read raw data
%             M = readmatrix(path + file,'Range',4);
%             M(:,8)=[]
%             M(:,1:2)=[];
%         end
%         
%         function data = readFile(path)
%           
%             
%             fileID = fopen(path)
%             D = textscan(fileID)
%             %[header, M] = ReadFile(path +file);
%             
%             %M = readmatrix(file,'Range',4);
%             data = D;
%         end
        
        function changeVisibility(app, ~)
            if (app.isPressed)
                app.ChoosemuscleButtonGroup.Visible = 'on';
            else
                app.ChoosemuscleButtonGroup.Visible = 'off';
            end
        end
        
        function plotFunction(app, column)
            
            
            plot(app.UIAxes, (1:length(app.data(:,column)))', app.data(:,column))
            legend(app.UIAxes, {'channel1'}, 'Location', 'Best')
            xlim(app.UIAxes, [0, length(app.data(:,column))]);
            
            plot(app.UIAxes2, (1:length(app.fourier(:,column)))', app.fourier(:,column))
            legend(app.UIAxes2, {'channel1'}, 'Location', 'Best')
            xlim(app.UIAxes2, [0, length(app.fourier(:,column))]);
            
%             plot(app.UIAxes, (1:length(data(:,2)))', data(:,2))
%             legend(app.UIAxes, {'channel2'}, 'Location', 'Best')
%             %app.UIAxes.YLim = [0 10000];
%             xlim(app.UIAxes, [0, length(data(:,2))]);
%             
%             plot(app.UIAxes, (1:length(data(:,3)))', data(:,3))
%             legend(app.UIAxes, {'channel3'}, 'Location', 'Best')
%             %app.UIAxes.YLim = [0 10000];
%             xlim(app.UIAxes, [0, length(data(:,3))]);
%             
%             plot(app.UIAxes, (1:length(data(:,4)))', data(:,4))
%             legend(app.UIAxes, {'channel4'}, 'Location', 'Best')
%             %app.UIAxes.YLim = [0 10000];
%             xlim(app.UIAxes, [0, length(data(:,4))]);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            event = events('pass');
            DataofButtonGroupSelectionChanged(app, event);
            %[header, data] = ReadFile('C:\Users\maxim\OneDrive\Documenten\GitHub\DSP_sEMG\S1_MVC_delt_links.txt')
            
%             M = readmatrix('C:\Users\maxim\OneDrive\Documenten\GitHub\DSP_sEMG\S1_MVC_delt_links.txt', 'range', 4)
%             plot(app.UIAxes, (1:length(M(:,3)))', M(:,3))
%             legend(app.UIAxes, {'channel1'}, 'Location', 'Best')
%             %app.UIAxes.YLim = [0 10000];
%             xlim(app.UIAxes, [0, length(M(:,3))]);
            
            ChoosemuscleButtonGroupSelectionChanged(app, event);             
        end

        % Selection changed function: ChoosemuscleButtonGroup
        function ChoosemuscleButtonGroupSelectionChanged(app, event)
            selectedButton = app.ChoosemuscleButtonGroup.SelectedObject;
            switch selectedButton
                case app.ECR_rightButton
                    plotFunction(app, 5);
                    % changeSelection(app, 'C:\Users\maxim\OneDrive\Documenten\GitHub\DSP_sEMG\S1_MVC_ECR.txt')
                case app.delt_leftButton
                    plotFunction(app, 3);
                    %changeSelection(app, 'C:\Users\maxim\OneDrive\Documenten\GitHub\DSP_sEMG\S1_MVC_delt_links.txt')                    
                case app.delt_rightButton
                    plotFunction(app, 4);
                    %changeSelectiond(app, 'C:\Users\maxim\OneDrive\Documenten\GitHub\DSP_sEMG\S1_MVC_delt_rechts.txt')
                case app.trapezius_leftButton
                    plotFunction(app, 1);
                    %changeSelection(app, 'C:\Users\maxim\OneDrive\Documenten\GitHub\DSP_sEMG\S1_MVC_trapezius_links.txt')
                case app.trapezius_rightButton
                    plotFunction(app, 2);
                    %changeSelection(app, 'C:\Users\maxim\OneDrive\Documenten\GitHub\DSP_sEMG\S1_MVC_trapezius_rechts.txt')
                otherwise
            end
        end

        % Selection changed function: DataofButtonGroup
        function DataofButtonGroupSelectionChanged(app, event)
            selectedButton = app.DataofButtonGroup.SelectedObject;
            if (selectedButton == app.selectedmuscleButton)
                app.isPressed = true;
                changeVisibility(app, event);
            elseif (selectedButton == app.allmusclesButton)
                app.isPressed = false;
                changeVisibility(app, event)
            end
        end

        % Size changed function: ChoosemuscleButtonGroup
        function ChoosemuscleButtonGroupSizeChanged(app, event)
            
        end

        % Button pushed function: ReadfileButton
        function ReadfileButtonPushed(app, event)

            [file,path] = uigetfile('*.txt');
            fullPath = append(path, file);
            [app.header, app.data] = ReadFile(fullPath);
            
            M_mV = transformTo_mV(app.data, app.header.resolution);
            %fft
            app.fourier = fft(app.data,[],1);
            app.fourier(1,:)=0;
            
            %outlier removal
            numDerivation = 2;
            windowSize = 20;
            %no_outliers = FilterOutlierWindow(M_mV,numDerivation,windowSize);
            
            plotFunction(app, 1);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Raw data (mV)')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XTick = 1;
            app.UIAxes.XTickLabel = {'0'; '5'; '10'; '15'; '20'};
            app.UIAxes.YTick = 1000;
            app.UIAxes.YTickLabel = {'0'; '1000'; '2000'; '3000'; '4000'; '5000'};
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'FFT')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.sEMGLabel = uilabel(app.UIFigure);
            app.sEMGLabel.Text = 'sEMG';
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Without Outliers')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.SettingsPanel = uipanel(app.UIFigure);
            app.SettingsPanel.Title = 'Settings';
            app.ChoosemuscleButtonGroup = uibuttongroup(app.SettingsPanel);
            app.ChoosemuscleButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ChoosemuscleButtonGroupSelectionChanged, true);
            app.ChoosemuscleButtonGroup.Title = 'Choose muscle';
            app.ChoosemuscleButtonGroup.SizeChangedFcn = createCallbackFcn(app, @ChoosemuscleButtonGroupSizeChanged, true);
            app.ECR_rightButton = uiradiobutton(app.ChoosemuscleButtonGroup);
            app.ECR_rightButton.Text = 'ECR_right';
            app.delt_leftButton = uiradiobutton(app.ChoosemuscleButtonGroup);
            app.delt_leftButton.Text = 'delt_left';
            app.delt_rightButton = uiradiobutton(app.ChoosemuscleButtonGroup);
            app.delt_rightButton.Text = 'delt_right';
            app.trapezius_leftButton = uiradiobutton(app.ChoosemuscleButtonGroup);
            app.trapezius_leftButton.Text = 'trapezius_left';
            app.trapezius_leftButton.Value = true;
            app.trapezius_rightButton = uiradiobutton(app.ChoosemuscleButtonGroup);
            app.trapezius_rightButton.Text = 'trapezius_right';
            app.UITable = uitable(app.SettingsPanel);
            app.UITable.ColumnName = {'Sensor 1'; 'Sensor 2'; 'Sensor 3'; 'Sensor 4'; 'Sensor 5'};
            app.UITable.RowName = {};
            app.DataofButtonGroup = uibuttongroup(app.SettingsPanel);
            app.DataofButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DataofButtonGroupSelectionChanged, true);
            app.DataofButtonGroup.Title = 'Data of';
            app.selectedmuscleButton = uiradiobutton(app.DataofButtonGroup);
            app.selectedmuscleButton.Text = 'selected muscle';
            app.selectedmuscleButton.Value = true;
            app.allmusclesButton = uiradiobutton(app.DataofButtonGroup);
            app.allmusclesButton.Text = 'all muscles';
            app.ReadfileButton = uibutton(app.UIFigure, 'push');
            app.ReadfileButton.ButtonPushedFcn = createCallbackFcn(app, @ReadfileButtonPushed, true);
            app.ReadfileButton.Text = 'Read file';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tutorialApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end