classdef tutorialApp_autoreflow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        SettingsPanel               matlab.ui.container.Panel
        ChoosemuscleButtonGroup     matlab.ui.container.ButtonGroup
        ECR_rightButton             matlab.ui.control.RadioButton
        delt_leftButton             matlab.ui.control.RadioButton
        delt_rightButton            matlab.ui.control.RadioButton
        trapezius_leftButton        matlab.ui.control.RadioButton
        trapezius_rightButton       matlab.ui.control.RadioButton
        DataofButtonGroup           matlab.ui.container.ButtonGroup
        selectedmuscleButton        matlab.ui.control.RadioButton
        allmusclesButton            matlab.ui.control.RadioButton
        PlottheButtonGroup          matlab.ui.container.ButtonGroup
        rawdataButton               matlab.ui.control.RadioButton
        fouriertransformButton      matlab.ui.control.RadioButton
        datawithoutoutliersButton   matlab.ui.control.RadioButton
        databandpassfilteredButton  matlab.ui.control.RadioButton
        dataSavitzkyGolayfilteredButton  matlab.ui.control.RadioButton
        movingrmsofthedataButton    matlab.ui.control.RadioButton
        normalizeddataButton        matlab.ui.control.RadioButton
        ReadfileButton              matlab.ui.control.Button
        RightPanel                  matlab.ui.container.Panel
        sEMGLabel                   matlab.ui.control.Label
        PlotPanel                   matlab.ui.container.Panel
        UIAxes                      matlab.ui.control.UIAxes
        PlotPanel_2                 matlab.ui.container.Panel
        UIAxes2                     matlab.ui.control.UIAxes
        PlotPanel_3                 matlab.ui.container.Panel
        UIAxes3                     matlab.ui.control.UIAxes
        PlotPanel_4                 matlab.ui.container.Panel
        UIAxes4                     matlab.ui.control.UIAxes
        PlotPanel_5                 matlab.ui.container.Panel
        UIAxes5                     matlab.ui.control.UIAxes
        PlotPanel_6                 matlab.ui.container.Panel
        UIAxes6                     matlab.ui.control.UIAxes
        PlotPanel_7                 matlab.ui.container.Panel
        UIAxes7                     matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        header = []
        data = [0,0,0,0,0]
        samplingRate
        fourier = [0,0,0,0,0]
        no_outliers
        filtered
        sgf
        y
        movrmsExp
        windowLength
        NormalizedData
    end
    methods (Access = public)               
        function plotFunction(app, column)
            plot(app.UIAxes, (1:length(app.data(:,column)))', app.data(:,column))
            %legend(app.UIAxes, {'channel1'}, 'Location', 'Best')
            xlim(app.UIAxes, [0, length(app.data(:,column))]);
            
            plot(app.UIAxes2, (1:length(app.fourier(:,column)))', app.fourier(:,column))
            xlim(app.UIAxes2, [0, length(app.fourier(:,column))]);
            
            plot(app.UIAxes3, (1:length(app.no_outliers(:,column)))', app.no_outliers(:,column))
            xlim(app.UIAxes3, [0, length(app.no_outliers(:,column))]);
            
%             y = bandpass(app.no_outliers(:,column), [100 300], app.samplingRate);
%             plot(app.UIAxes4, (1:length(y))', y)

            plot(app.UIAxes4, (1:length(app.filtered(:,column)))', app.filtered(:,column))
            xlim(app.UIAxes4, [0, length(app.filtered(:,column))]);
            
            plot(app.UIAxes5, (1:length(app.sgf(:,column)))', app.sgf(:,column))
            xlim(app.UIAxes5, [0, length(app.sgf(:,column))]);
            
%             app.rms = app.movrmsExp(app.y(:,column))';
%             plot(app.UIAxes6, (1:length(app.rms(:,column)))', app.rms(:,column))
%             xlim(app.UIAxes6, [0, length(app.rms(:,column))]);
            
            plot(app.UIAxes7, (1:length(app.NormalizedData(:,column)))', app.NormalizedData(:,column))
            xlim(app.UIAxes7, [0, length(app.NormalizedData(:,column))]);
            
%             plot(app.UIAxes, (1:length(data(:,2)))', data(:,2))
%             legend(app.UIAxes, {'channel2'}, 'Location', 'Best')
%             %app.UIAxes.YLim = [0 10000];
%             xlim(app.UIAxes, [0, length(data(:,2))]);
%             
%             plot(app.UIAxes, (1:length(data(:,3)))', data(:,3))
%             legend(app.UIAxes, {'channel3'}, 'Location', 'Best')
%             %app.UIAxes.YLim = [0 10000];
%             xlim(app.UIAxes, [0, length(data(:,3))]);
%             
%             plot(app.UIAxes, (1:length(data(:,4)))', data(:,4))
%             legend(app.UIAxes, {'channel4'}, 'Location', 'Best')
%             %app.UIAxes.YLim = [0 10000];
%             xlim(app.UIAxes, [0, length(data(:,4))]);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            event = events('pass');
            
            % set default visibility of plots
            app.PlotPanel.Visible = 'on';
            app.PlotPanel_2.Visible = 'off';
            app.PlotPanel_3.Visible = 'off';
            app.PlotPanel_4.Visible = 'off';
            app.PlotPanel_5.Visible = 'off';
            app.PlotPanel_6.Visible = 'off';
            app.PlotPanel_7.Visible = 'off';
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {209, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Selection changed function: ChoosemuscleButtonGroup
        function ChoosemuscleButtonGroupSelectionChanged(app, event)
            selectedButton = app.ChoosemuscleButtonGroup.SelectedObject;
            switch selectedButton
                case app.ECR_rightButton
                    plotFunction(app, 5);
                case app.delt_leftButton
                    plotFunction(app, 3);                  
                case app.delt_rightButton
                    plotFunction(app, 4);
                case app.trapezius_leftButton
                    plotFunction(app, 1);
                case app.trapezius_rightButton
                    plotFunction(app, 2);
                otherwise
            end
        end

        % Selection changed function: DataofButtonGroup
        function DataofButtonGroupSelectionChanged(app, event)
            selectedButton = app.DataofButtonGroup.SelectedObject;
            if (selectedButton == app.selectedmuscleButton)
                app.ChoosemuscleButtonGroup.Visible = 'on';
            elseif (selectedButton == app.allmusclesButton)
                app.ChoosemuscleButtonGroup.Visible = 'off';
            end
        end

        % Button pushed function: ReadfileButton
        function ReadfileButtonPushed(app, event)
            %% Load raw data
            [file,path] = uigetfile('*.txt');
            fullPath = append(path, file);
            [app.header, app.data] = ReadFile(fullPath);
%             Date = header.date;
%             time = header.time;
            app.samplingRate = app.header.samplingRate;
%             resolution = header.resolution;
            
            %% Transform raw data to mV
            M_mV = transformTo_mV(app.data, app.header.resolution);
            
            %% FFT analysis           
            app.fourier = fft(app.data,[],1);
            % filter peak value
            app.fourier(1,:)=0;
            
            %% Outlier removal
            numDerivation = 2;
            windowSize = 20;
            app.no_outliers = FilterOutlierWindow(M_mV,numDerivation,windowSize);
            
            %%% Processing track A
            % rectify data
            app.no_outliers = abs(app.no_outliers);
            
            %% Bandpassfilter
            % cutoffrequency
            lowCutoff = 100;
            highCutoff = 300;
            app.filtered = BandpassFilter(app.header,app.no_outliers,lowCutoff,highCutoff,app.samplingRate);

            %% Savitzky-Golay filtering
            order = 20;
            framelen = 219;
            app.sgf = Savitzky_GolayFilter(app.no_outliers,order,framelen);
            
            %%% Processing track B
            %% Moving RMS
            app.windowLength = 20;
            overlap = app.windowLength -1; % default windowLength -1
            
            %app.movrmsExp = MovingRMS('WindowLength',app.windowLength,'OverlapLength',overlap);
            
            app.y = abs(app.data);
            
            %% MVC Normalisation
            app.NormalizedData = MVC(app.no_outliers,"S1");

            plotFunction(app, 1);
        end

        % Selection changed function: PlottheButtonGroup
        function PlottheButtonGroupSelectionChanged(app, event)
            selectedButton = app.PlottheButtonGroup.SelectedObject;
            app.PlotPanel.Visible = 'off';
            app.PlotPanel_2.Visible = 'off';
            app.PlotPanel_3.Visible = 'off';
            app.PlotPanel_4.Visible = 'off';
            app.PlotPanel_5.Visible = 'off';
            app.PlotPanel_6.Visible = 'off';
            app.PlotPanel_7.Visible = 'off';
            
            if (selectedButton == app.rawdataButton)
                app.PlotPanel.Visible = 'on';
            elseif (selectedButton == app.fouriertransformButton)
                app.PlotPanel_2.Visible = 'on';
            elseif (selectedButton == app.datawithoutoutliersButton)
                app.PlotPanel_3.Visible = 'on';
            elseif (selectedButton == app.databandpassfilteredButton)
                app.PlotPanel_4.Visible = 'on';
            elseif (selectedButton == app.dataSavitzkyGolayfilteredButton)
                app.PlotPanel_5.Visible = 'on';
            elseif (selectedButton == app.movingrmsofthedataButton)
                app.PlotPanel_6.Visible = 'on';
            elseif (selectedButton == app.normalizeddataButton)
                app.PlotPanel_7.Visible = 'on';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SettingsPanel = uipanel(app.LeftPanel);
            app.SettingsPanel.Title = 'Settings';
            app.ChoosemuscleButtonGroup = uibuttongroup(app.SettingsPanel);
            app.ChoosemuscleButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ChoosemuscleButtonGroupSelectionChanged, true);
            app.ChoosemuscleButtonGroup.Title = 'Choose muscle';
            app.ECR_rightButton = uiradiobutton(app.ChoosemuscleButtonGroup);
            app.ECR_rightButton.Text = 'ECR_right';
            app.delt_leftButton = uiradiobutton(app.ChoosemuscleButtonGroup);
            app.delt_leftButton.Text = 'delt_left';
            app.delt_rightButton = uiradiobutton(app.ChoosemuscleButtonGroup);
            app.delt_rightButton.Text = 'delt_right';
            app.trapezius_leftButton = uiradiobutton(app.ChoosemuscleButtonGroup);
            app.trapezius_leftButton.Text = 'trapezius_left';
            app.trapezius_leftButton.Value = true;
            app.trapezius_rightButton = uiradiobutton(app.ChoosemuscleButtonGroup);
            app.trapezius_rightButton.Text = 'trapezius_right';
            app.DataofButtonGroup = uibuttongroup(app.SettingsPanel);
            app.DataofButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DataofButtonGroupSelectionChanged, true);
            app.DataofButtonGroup.Title = 'Data of';
            app.selectedmuscleButton = uiradiobutton(app.DataofButtonGroup);
            app.selectedmuscleButton.Text = 'selected muscle';
            app.selectedmuscleButton.Value = true;
            app.allmusclesButton = uiradiobutton(app.DataofButtonGroup);
            app.allmusclesButton.Text = 'all muscles';
            app.PlottheButtonGroup = uibuttongroup(app.SettingsPanel);
            app.PlottheButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlottheButtonGroupSelectionChanged, true);
            app.PlottheButtonGroup.Title = 'Plot the';
            app.rawdataButton = uiradiobutton(app.PlottheButtonGroup);
            app.rawdataButton.Text = 'raw data';
            app.rawdataButton.Value = true;
            app.fouriertransformButton = uiradiobutton(app.PlottheButtonGroup);
            app.fouriertransformButton.Text = 'fourier transform';
            app.datawithoutoutliersButton = uiradiobutton(app.PlottheButtonGroup);
            app.datawithoutoutliersButton.Text = 'data without outliers';
            app.databandpassfilteredButton = uiradiobutton(app.PlottheButtonGroup);
            app.databandpassfilteredButton.Text = 'data bandpass filtered';
            app.dataSavitzkyGolayfilteredButton = uiradiobutton(app.PlottheButtonGroup);
            app.dataSavitzkyGolayfilteredButton.Text = 'data Savitzky-Golay filtered';
            app.movingrmsofthedataButton = uiradiobutton(app.PlottheButtonGroup);
            app.movingrmsofthedataButton.Text = 'moving rms of the data';
            app.normalizeddataButton = uiradiobutton(app.PlottheButtonGroup);
            app.normalizeddataButton.Text = 'normalized data';
            app.ReadfileButton = uibutton(app.LeftPanel, 'push');
            app.ReadfileButton.ButtonPushedFcn = createCallbackFcn(app, @ReadfileButtonPushed, true);
            app.ReadfileButton.Text = 'Read file';
            app.RightPanel = uipanel(app.GridLayout);
            app.sEMGLabel = uilabel(app.RightPanel);
            app.sEMGLabel.Text = 'sEMG';
            app.PlotPanel = uipanel(app.RightPanel);
            app.PlotPanel.Title = 'Plot';
            app.UIAxes = uiaxes(app.PlotPanel);
            title(app.UIAxes, 'Raw data')
            xlabel(app.UIAxes, 'Samples')
            ylabel(app.UIAxes, 'Voltage (mV)')
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes.XTickLabel = {'0'; '0.2'; '0.4'; '0.6'; '0.8'; '1'};
            app.UIAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes.YTickLabel = {'0'; '0.2'; '0.4'; '0.6'; '0.8'; '1'};
            app.PlotPanel_2 = uipanel(app.RightPanel);
            app.PlotPanel_2.Title = 'Plot';
            app.UIAxes2 = uiaxes(app.PlotPanel_2);
            title(app.UIAxes2, 'FFT')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes2.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes2.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.PlotPanel_3 = uipanel(app.RightPanel);
            app.PlotPanel_3.Title = 'Plot';
            app.UIAxes3 = uiaxes(app.PlotPanel_3);
            title(app.UIAxes3, 'Without Outliers')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.PlotPanel_4 = uipanel(app.RightPanel);
            app.PlotPanel_4.Title = 'Plot';
            app.UIAxes4 = uiaxes(app.PlotPanel_4);
            title(app.UIAxes4, 'with bandpass filter')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            app.PlotPanel_5 = uipanel(app.RightPanel);
            app.PlotPanel_5.Title = 'Plot';
            app.UIAxes5 = uiaxes(app.PlotPanel_5);
            title(app.UIAxes5, 'with Savitzky-Golay filter')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            app.PlotPanel_6 = uipanel(app.RightPanel);
            app.PlotPanel_6.Title = 'Plot';
            app.UIAxes6 = uiaxes(app.PlotPanel_6);
            title(app.UIAxes6, 'moving rms')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            app.PlotPanel_7 = uipanel(app.RightPanel);
            app.PlotPanel_7.Title = 'Plot';
            app.UIAxes7 = uiaxes(app.PlotPanel_7);
            title(app.UIAxes7, 'Normalized')
            xlabel(app.UIAxes7, 'X')
            ylabel(app.UIAxes7, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tutorialApp_autoreflow

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end